classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        app1UIFigure               matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        MathTab                    matlab.ui.container.Tab
        GenerateTestButton         matlab.ui.control.Button
        ShowAnswerButton           matlab.ui.control.Button
        CheckBox                   matlab.ui.control.CheckBox
        CheckBox_2                 matlab.ui.control.CheckBox
        CheckBox_3                 matlab.ui.control.CheckBox
        CheckBox_4                 matlab.ui.control.CheckBox
        DifficultyLabel            matlab.ui.control.Label
        DifficultyDropDown         matlab.ui.control.DropDown
        QuantityDropDownLabel      matlab.ui.control.Label
        QuantityDropDown           matlab.ui.control.DropDown
        NumbersDropDownLabel       matlab.ui.control.Label
        NumbersDropDown            matlab.ui.control.DropDown
        ChineseTab                 matlab.ui.container.Tab
        DifficultyDropDown_2Label  matlab.ui.control.Label
        DifficultyDropDown_2       matlab.ui.control.DropDown
        QuantityDropDown_2Label    matlab.ui.control.Label
        QuantityDropDown_2         matlab.ui.control.DropDown
        GenerateTestButton_2       matlab.ui.control.Button
        ShowAnswerButton_2         matlab.ui.control.Button
    end


    methods (Access = private)

        % Button pushed function: GenerateTestButton
        function GenerateTestButtonPushed(app, event)
            global data1;
            global data2;
            
            %生成随机数存入data1中
            data1=zeros(str2num(app.QuantityDropDown.Value),str2num(app.NumbersDropDown.Value));
            for countquan = 1:str2num(app.QuantityDropDown.Value)
                for countnumb = 1:str2num(app.NumbersDropDown.Value)
                    data1(countquan,countnumb) = randi([1 str2num(app.DifficultyDropDown.Value)],1,1);
                end
            end
            
            %判断复选框的勾选情况，启用不同的四则运算算法（+,-,*,/）
            data2=zeros(str2num(app.QuantityDropDown.Value),str2num(app.NumbersDropDown.Value)-1);
            for countquan = 1:str2num(app.QuantityDropDown.Value)
                for countnumb = 1:str2num(app.NumbersDropDown.Value)-1
                    if (app.CheckBox.Value == 1 && app.CheckBox_2.Value == 0 && app.CheckBox_2.Value == 0 && app.CheckBox_3.Value == 0)
                        data2(countquan,countnumb) = randi([1 1],1,1);
                    elseif (app.CheckBox.Value == 1 && app.CheckBox_2.Value == 1 && app.CheckBox_3.Value == 0 && app.CheckBox_4.Value == 0)
                        data2(countquan,countnumb) = randi([1 2],1,1);
                    elseif (app.CheckBox.Value == 1 && app.CheckBox_2.Value == 1 && app.CheckBox_3.Value == 1 && app.CheckBox_4.Value == 0)
                        data2(countquan,countnumb) = randi([1 3],1,1);
                    elseif (app.CheckBox.Value == 1 && app.CheckBox_2.Value == 1 && app.CheckBox_3.Value == 1 && app.CheckBox_4.Value == 1)
                        data2(countquan,countnumb) = randi([1 4],1,1);
                    end
                end
            end
             
             %将四则运算算法用文字表示，并将数字和符号存入table中
             if (str2num(app.NumbersDropDown.Value) == 2) %如果需要运算的数字有2个，则按如下操作
                T(:,1) = table(data1(:,1));
                for countquan = 1:str2num(app.QuantityDropDown.Value)
                        if (data2(countquan,1) == 1)
                            T(countquan,2) = {"+"};
                        elseif (data2(countquan,1) == 2)
                            T(countquan,2) = {"-"};
                        elseif (data2(countquan,1) == 3)
                            T(countquan,2) = {"×"};
                        elseif (data2(countquan,1) == 4)
                            T(countquan,2) = {"÷"};
                        end
                end
                 T(:,3) = table(data1(:,2));
                 T(:,4) = {"="};
            elseif (str2num(app.NumbersDropDown.Value) == 3)%如果需要运算的数字有3个，则按如下操作
                T(:,1) = table(data1(:,1));
                for countquan = 1:str2num(app.QuantityDropDown.Value)
                        if (data2(countquan,1) == 1)
                            T(countquan,2) = {"+"};
                        elseif (data2(countquan,1) == 2)
                            T(countquan,2) = {"-"};
                        elseif (data2(countquan,1) == 3)
                            T(countquan,2) = {"×"};
                        elseif (data2(countquan,1) == 4)
                            T(countquan,2) = {"÷"};
                        end
                end               
                T(:,3) = table(data1(:,2));
                for countquan = 1:str2num(app.QuantityDropDown.Value)
                        if (data2(countquan,2) == 1)
                            T(countquan,4) = {"+"};
                        elseif (data2(countquan,2) == 2)
                            T(countquan,4) = {"-"};
                        elseif (data2(countquan,2) == 3)
                            T(countquan,4) = {"×"};
                        elseif (data2(countquan,2) == 4)
                            T(countquan,4) = {"÷"};
                        end
                end
                T(:,5) = table(data1(:,3));
                T(:,6) = {"="};
            elseif (str2num(app.NumbersDropDown.Value) == 4)%如果需要运算的数字有4个，则按如下操作
                T(:,1) = table(data1(:,1));
                for countquan = 1:str2num(app.QuantityDropDown.Value)
                        if (data2(countquan,1) == 1)
                            T(countquan,2) = {"+"};
                        elseif (data2(countquan,1) == 2)
                            T(countquan,2) = {"-"};
                        elseif (data2(countquan,1) == 3)
                            T(countquan,2) = {"×"};
                        elseif (data2(countquan,1) == 4)
                            T(countquan,2) = {"÷"};
                        end
                end
                T(:,3) = table(data1(:,2));
                for countquan = 1:str2num(app.QuantityDropDown.Value)
                        if (data2(countquan,2) == 1)
                            T(countquan,4) = {"+"};
                        elseif (data2(countquan,2) == 2)
                            T(countquan,4) = {"-"};
                        elseif (data2(countquan,2) == 3)
                            T(countquan,4) = {"×"};
                        elseif (data2(countquan,2) == 4)
                            T(countquan,4) = {"÷"};
                        end
                end
                T(:,5) = table(data1(:,3));
                for countquan = 1:str2num(app.QuantityDropDown.Value)
                        if (data2(countquan,3) == 1)
                            T(countquan,6) = {"+"};
                        elseif (data2(countquan,3) == 2)
                            T(countquan,6) = {"-"};
                        elseif (data2(countquan,3) == 3)
                            T(countquan,6) = {"×"};
                        elseif (data2(countquan,3) == 4)
                            T(countquan,6) = {"÷"};
                        end
                end
                T(:,7) = table(data1(:,4)); 
                T(:,8) = {"="};
             end
            
            %把变量存入工作区，调试用
            assignin('base','T',T);
            assignin('base','data1',data1);
            assignin('base','data2',data2);

            %将table存入GenerateTest.txt中
            writetable(T,'GenerateTest.txt','Delimiter',' ');
            %通过notepad程序将GenerateTest.txt文本打开
            !start notepad.exe GenerateTest.txt
        end

        % Button pushed function: ShowAnswerButton
        function ShowAnswerButtonPushed(app, event)
            global data1;
            global data2;
            data3 = strings(str2num(app.QuantityDropDown.Value),str2num(app.QuantityDropDown.Value)-1);
            data4 = strings(str2num(app.QuantityDropDown.Value),1);
            for countquan = 1:str2num(app.QuantityDropDown.Value)%将复选框选中的四则运算算法用标准写法替换
                for countnumb = 1:str2num(app.NumbersDropDown.Value)-1
                        if (data2(countquan,countnumb) == 1)
                            data3(countquan,countnumb) = '+';
                        elseif (data2(countquan,countnumb) == 2)
                            data3(countquan,countnumb) = '-';
                        elseif (data2(countquan,countnumb) == 3)
                            data3(countquan,countnumb) = '*';
                        elseif (data2(countquan,countnumb) == 4)
                            data3(countquan,countnumb) = '/';
                        end
                end
            end
            
            sp = {32};%空格符，使输出整齐美观
            
            %在不同计算个数的情况下，将数字和符号合并为字符串，为计算提供方便
            if (str2num(app.NumbersDropDown.Value) == 2)
                for countquan = 1:str2num(app.QuantityDropDown.Value)
                    data4(countquan) = strcat(int2str(data1(countquan,1)),sp,data3(countquan,1),sp,int2str(data1(countquan,2))); 
                end
            elseif (str2num(app.NumbersDropDown.Value) == 3)
                for countquan = 1:str2num(app.QuantityDropDown.Value)
                    data4(countquan) = strcat(int2str(data1(countquan,1)),sp,data3(countquan,1),sp,int2str(data1(countquan,2)),sp,data3(countquan,2),sp,int2str(data1(countquan,3))); 
                end
            elseif (str2num(app.NumbersDropDown.Value) == 4)
                for countquan = 1:str2num(app.QuantityDropDown.Value)
                    data4(countquan) = strcat(int2str(data1(countquan,1)),sp,data3(countquan,1),sp,int2str(data1(countquan,2)),sp,data3(countquan,2),sp,int2str(data1(countquan,3)),sp,data3(countquan,3),sp,int2str(data1(countquan,4))); 
                end
            end
            
            for countquan = 1:str2num(app.QuantityDropDown.Value)
                data4(countquan) = strcat(data4(countquan),sp,'=',sp,num2str(eval(data4(countquan))));
                %使用eval函数对字符串进行计算
            end
            %调试用
            assignin('base','data3',data3);
            assignin('base','data4',data4);
            %将计算结果保存到ShowAnswer.txt中，并通过notepad程序自动打开
            answer = fopen('ShowAnswer.txt','w+');
            fprintf(answer,'%s\n',data4);
            fclose(answer);
            !start notepad.exe ShowAnswer.txt

        end

        % Button pushed function: GenerateTestButton_2
        function GenerateTestButton_2Pushed(app, event)
            global randlist;
            load chinese.mat;%将chinese.mat的数据导入进matlab
            randlist =  randperm(str2num(app.QuantityDropDown_2.Value));%将题号打乱
            gushi = strings(str2num(app.QuantityDropDown_2.Value),1);
            
            %根据Difficulty中的设置，选择需要填的是前句还是后句
            if (app.DifficultyDropDown_2.Value == '1')
                for countquan = 1:str2num(app.QuantityDropDown_2.Value)
                    gushi(countquan,1) = strcat('-------------- , ',chinese(randlist(1,countquan),2));   
                end
            elseif (app.DifficultyDropDown_2.Value == '2')
                for countquan = 1:str2num(app.QuantityDropDown_2.Value)
                    gushi(countquan,1) = strcat(chinese(randlist(1,countquan),1),' , --------------');
                end
            elseif (app.DifficultyDropDown_2.Value == '3')
                for countquan = 1:str2num(app.QuantityDropDown_2.Value)/2
                    gushi(countquan*2-1,1) = strcat(chinese(randlist(1,countquan*2-1),1),' , -------------');
                    gushi(countquan*2,1) = strcat('-------------- , ',chinese(randlist(1,countquan*2),2)); 
                end
            end
            
            %调试用
            assignin('base','randlist',randlist);
            assignin('base','gushi',gushi);
            
            %将生成的题目保存到Chinese.txt中，并用notepad程序自动打开
            test = fopen('Chinese.txt','w+');
            fprintf(test,'%s\n',gushi);
            fclose(test);
            !start notepad.exe Chinese.txt
        end

        % Button pushed function: ShowAnswerButton_2
        function ShowAnswerButton_2Pushed(app, event)
            global randlist;
            load chinese.mat%导入chinese.mat
            
            %生成答案
            for countquan = 1:str2num(app.QuantityDropDown_2.Value)
                    gushi(countquan,1) = strcat(chinese(randlist(1,countquan),1),',',chinese(randlist(1,countquan),2));   
            end
            
            %将答案保存为ChineseAnswer.txt，并通过notepad打开
            answer = fopen('ChineseAnswer.txt','w+');
            fprintf(answer,'%s\n',gushi);
            fclose(answer);
            !start notepad.exe ChineseAnswer.txt
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.app1UIFigure = uifigure;
            app.app1UIFigure.Name = 'app1';
            app.TabGroup = uitabgroup(app.app1UIFigure);
            app.MathTab = uitab(app.TabGroup);
            app.MathTab.Title = 'Math';
            app.GenerateTestButton = uibutton(app.MathTab, 'push');
            app.GenerateTestButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTestButtonPushed, true);
            app.GenerateTestButton.Text = 'Generate Test';
            app.ShowAnswerButton = uibutton(app.MathTab, 'push');
            app.ShowAnswerButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAnswerButtonPushed, true);
            app.ShowAnswerButton.Text = 'Show Answer';
            app.CheckBox = uicheckbox(app.MathTab);
            app.CheckBox.Text = '+';
            app.CheckBox.Value = true;
            app.CheckBox_2 = uicheckbox(app.MathTab);
            app.CheckBox_2.Text = '-';
            app.CheckBox_3 = uicheckbox(app.MathTab);
            app.CheckBox_3.Text = '×';
            app.CheckBox_4 = uicheckbox(app.MathTab);
            app.CheckBox_4.Text = '÷';
            app.DifficultyLabel = uilabel(app.MathTab);
            app.DifficultyLabel.Text = 'Difficulty';
            app.DifficultyDropDown = uidropdown(app.MathTab);
            app.DifficultyDropDown.Items = {'10', '20', '50', '100'};
            app.DifficultyDropDown.Value = '10';
            app.QuantityDropDownLabel = uilabel(app.MathTab);
            app.QuantityDropDownLabel.Text = 'Quantity';
            app.QuantityDropDown = uidropdown(app.MathTab);
            app.QuantityDropDown.Items = {'20', '50', '100'};
            app.QuantityDropDown.Value = '20';
            app.NumbersDropDownLabel = uilabel(app.MathTab);
            app.NumbersDropDownLabel.Text = 'Numbers';
            app.NumbersDropDown = uidropdown(app.MathTab);
            app.NumbersDropDown.Items = {'2', '3', '4'};
            app.NumbersDropDown.Value = '2';
            app.ChineseTab = uitab(app.TabGroup);
            app.ChineseTab.Title = 'Chinese';
            app.DifficultyDropDown_2Label = uilabel(app.ChineseTab);
            app.DifficultyDropDown_2Label.Text = 'Difficulty';
            app.DifficultyDropDown_2 = uidropdown(app.ChineseTab);
            app.DifficultyDropDown_2.Items = {'left', 'right', 'mix'};
            app.DifficultyDropDown_2.ItemsData = {'1', '2', '3'};
            app.DifficultyDropDown_2.Value = '1';
            app.QuantityDropDown_2Label = uilabel(app.ChineseTab);
            app.QuantityDropDown_2Label.Text = 'Quantity';
            app.QuantityDropDown_2 = uidropdown(app.ChineseTab);
            app.QuantityDropDown_2.Items = {'10', '30', '50'};
            app.QuantityDropDown_2.Value = '10';
            app.GenerateTestButton_2 = uibutton(app.ChineseTab, 'push');
            app.GenerateTestButton_2.ButtonPushedFcn = createCallbackFcn(app, @GenerateTestButton_2Pushed, true);
            app.GenerateTestButton_2.Text = 'GenerateTest';
            app.ShowAnswerButton_2 = uibutton(app.ChineseTab, 'push');
            app.ShowAnswerButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowAnswerButton_2Pushed, true);
            app.ShowAnswerButton_2.Text = 'ShowAnswer';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.app1UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.app1UIFigure)
        end
    end
end